classdef appAbbIrb120 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        robEdit                matlab.ui.control.EditField
        ExportarButton         matlab.ui.control.Button
        ImportarButton         matlab.ui.control.Button
        RotXLabel              matlab.ui.control.Label
        RotYLabel              matlab.ui.control.Label
        RotZLabel              matlab.ui.control.Label
        PosZLabel              matlab.ui.control.Label
        PosYLabel              matlab.ui.control.Label
        PosXLabel              matlab.ui.control.Label
        q6Label                matlab.ui.control.Label
        q5Label                matlab.ui.control.Label
        q4Label                matlab.ui.control.Label
        q3Label                matlab.ui.control.Label
        q2Label                matlab.ui.control.Label
        q1Label                matlab.ui.control.Label
        InicioButton           matlab.ui.control.Button
        dRotXEdit              matlab.ui.control.EditField
        RotX1Button            matlab.ui.control.Button
        RotX2Button            matlab.ui.control.Button
        dRotYEdit              matlab.ui.control.EditField
        RotY1Button            matlab.ui.control.Button
        RotY2Button            matlab.ui.control.Button
        dRotZEdit              matlab.ui.control.EditField
        RotZ1Button            matlab.ui.control.Button
        RotZ2Button            matlab.ui.control.Button
        dPosZEdit              matlab.ui.control.EditField
        PosZ1Button            matlab.ui.control.Button
        PosZ2Button            matlab.ui.control.Button
        dPosYEdit              matlab.ui.control.EditField
        PosY1Button            matlab.ui.control.Button
        PosY2Button            matlab.ui.control.Button
        dPosXEdit              matlab.ui.control.EditField
        PosX1Button            matlab.ui.control.Button
        PosX2Button            matlab.ui.control.Button
        PosYEdit               matlab.ui.control.EditField
        RotXEdit               matlab.ui.control.EditField
        RotYEdit               matlab.ui.control.EditField
        RotZEdit               matlab.ui.control.EditField
        PosZEdit               matlab.ui.control.EditField
        PosXEdit               matlab.ui.control.EditField
        q6Edit                 matlab.ui.control.EditField
        q5Edit                 matlab.ui.control.EditField
        q4Edit                 matlab.ui.control.EditField
        q3Edit                 matlab.ui.control.EditField
        q2Edit                 matlab.ui.control.EditField
        q1Edit                 matlab.ui.control.EditField
        q6Slider               matlab.ui.control.Slider
        q5Slider               matlab.ui.control.Slider
        q4Slider               matlab.ui.control.Slider
        q3Slider               matlab.ui.control.Slider
        q2Slider               matlab.ui.control.Slider
        q1Slider               matlab.ui.control.Slider
        TeacherABBirb120Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        rob % Objeto Kin
        hwobj; % Matriz homogénea del objeto de trabajo
    end
    
    methods (Access = private)
        
        function q = LeerJointSlider(app)
               q= zeros(1,6);
               app.q1Slider.Value;
               q(1)= app.q1Slider.Value;
               q(2)= app.q2Slider.Value;
               q(3)= app.q3Slider.Value;
               q(4)= app.q4Slider.Value;
               q(5)= app.q5Slider.Value;
               q(6)= app.q6Slider.Value;
               q= q*pi/180;         
        end
        function q= LeerJointEdit(app)
               q= zeros(1,6);
               q(1)= str2double(app.q1Edit.Value);
               q(2)= str2double(app.q2Edit.Value);
               q(3)= str2double(app.q3Edit.Value);
               q(4)= str2double(app.q4Edit.Value);
               q(5)= str2double(app.q5Edit.Value);
               q(6)= str2double(app.q6Edit.Value);
               q= q*pi/180;     
        end
        function EscribirJointEdit(app, q)
               q= q*180/pi;
               app.q1Edit.Value= sprintf('%.0f', q(1));
               app.q2Edit.Value= sprintf('%.0f', q(2));
               app.q3Edit.Value= sprintf('%.0f', q(3));
               app.q4Edit.Value= sprintf('%.0f', q(4));
               app.q5Edit.Value= sprintf('%.0f', q(5));
               app.q6Edit.Value= sprintf('%.0f', q(6));
        end
        function EscribirJointSlider(app, q)
               q= q*180/pi;
               app.q1Slider.Value= q(1);
               app.q2Slider.Value= q(2);
               app.q3Slider.Value= q(3);
               app.q4Slider.Value= q(4);
               app.q5Slider.Value= q(5);
               app.q6Slider.Value= q(6);
        end
        function pose= LeerPose(app)
                pose= zeros(1,6);
                pose(1)= str2double(app.PosXEdit.Value);
                pose(2)= str2double(app.PosYEdit.Value);
                pose(3)= str2double(app.PosZEdit.Value);
                pose(4)= str2double(app.RotZEdit.Value);
                pose(5)= str2double(app.RotYEdit.Value);
                pose(6)= str2double(app.RotXEdit.Value);
                pose(1:3)= pose(1:3)*1e-3;
                pose(4:6)= pose(4:6)*pi/180;
        end
        function EscribirPose(app, pose)
               pose(1:3)= pose(1:3)*1e3;
               pose(4:6)= pose(4:6)*180/pi;
               app.PosXEdit.Value= sprintf('%.0f', pose(1));
               app.PosYEdit.Value= sprintf('%.0f', pose(2));
               app.PosZEdit.Value= sprintf('%.0f', pose(3));
               app.RotZEdit.Value= sprintf('%.0f', pose(4));
               app.RotYEdit.Value= sprintf('%.0f', pose(5));
               app.RotXEdit.Value= sprintf('%.0f', pose(6));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, rob)

            app.rob= rob; % robot
            %app.rob= evalin('base', 'rob');
            app.hwobj= [eye(3),zeros(3,1); zeros(1,3),1]; % objeto trabajo
            q= app.rob.Joint;
            pose= app.rob.Pose;
            app.EscribirJointSlider(q);
            app.EscribirJointEdit(q);
            app.EscribirPose(pose);
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            % Sincronizar el robot con la posición del Teacher.
            % Es interesante cuando se haya movido el robot desde fuera de la app.
            q= [0,0,0,0,90,0]*pi/180;
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirJointSlider(q);
            app.EscribirJointEdit(q);
            app.EscribirPose(pose);            
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            % Leer la posición de la cremallera y mueve 
            q= app.LeerJointSlider();
            app.EscribirJointEdit(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            % Leer la posición de la cremallera y mueve 
            q= app.LeerJointSlider();
            app.EscribirJointEdit(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            % Leer la posición de la cremallera y mueve 
            q= app.LeerJointSlider();
            app.EscribirJointEdit(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);            
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            % Leer la posición de la cremallera y mueve 
            q= app.LeerJointSlider();
            app.EscribirJointEdit(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)

            % Leer la posición de la cremallera y mueve 
            q= app.LeerJointSlider();
            app.EscribirJointEdit(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            % Leer la posición de la cremallera y mueve 
            q= app.LeerJointSlider();
            app.EscribirJointEdit(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);
        end

        % Value changed function: q1Edit
        function q1EditValueChanged(app, event)
            % Leer la posición del edit y mueve 
            q= app.LeerJointEdit();
            app.EscribirJointSlider(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);            
        end

        % Value changed function: q2Edit
        function q2EditValueChanged(app, event)
            % Leer la posición del edit y mueve 
            q= app.LeerJointEdit();
            app.EscribirJointSlider(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);                        
        end

        % Value changed function: q3Edit
        function q3EditValueChanged(app, event)
            % Leer la posición del edit y mueve 
            q= app.LeerJointEdit();
            app.EscribirJointSlider(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);         
        end

        % Value changed function: q4Edit
        function q4EditValueChanged(app, event)
            % Leer la posición del edit y mueve 
            q= app.LeerJointEdit();
            app.EscribirJointSlider(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);         
        end

        % Value changed function: q5Edit
        function q5EditValueChanged(app, event)
            % Leer la posición del edit y mueve 
            q= app.LeerJointEdit();
            app.EscribirJointSlider(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);                     
        end

        % Value changed function: q6Edit
        function q6EditValueChanged(app, event)
            % Leer la posición del edit y mueve 
            q= app.LeerJointEdit();
            app.EscribirJointSlider(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);                     
        end

        % Value changed function: PosXEdit
        function PosXEditValueChanged(app, event)
            pose= app.LeerPose();
            app.rob.MoveJ(pose,app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);
        end

        % Value changed function: PosYEdit
        function PosYEditValueChanged(app, event)
            pose= app.LeerPose();
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);            
        end

        % Value changed function: RotZEdit
        function RotZEditValueChanged(app, event)
            pose= app.LeerPose();
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);            
        end

        % Value changed function: RotYEdit
        function RotYEditValueChanged(app, event)
            pose= app.LeerPose();
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);            
        end

        % Value changed function: RotXEdit
        function RotXEditValueChanged(app, event)
            pose= app.LeerPose();
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);            
        end

        % Value changed function: PosZEdit
        function PosZEditValueChanged(app, event)
            pose= app.LeerPose();
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);                        
        end

        % Button pushed function: PosX1Button
        function PosX1ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dPosXEdit.Value)*1e-3;
            pose(1)= pose(1)- dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);  
        end

        % Button pushed function: PosX2Button
        function PosX2ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dPosXEdit.Value)*1e-3;
            pose(1)= pose(1)+ dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);              
        end

        % Button pushed function: PosY1Button
        function PosY1ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dPosYEdit.Value)*1e-3;
            pose(2)= pose(2)- dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);  
        end

        % Button pushed function: PosY2Button
        function PosY2ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dPosYEdit.Value)*1e-3;
            pose(2)= pose(2)+ dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);              
        end

        % Button pushed function: PosZ1Button
        function PosZ1ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dPosZEdit.Value)*1e-3;
            pose(3)= pose(3)- dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);  
        end

        % Button pushed function: PosZ2Button
        function PosZ2ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dPosZEdit.Value)*1e-3;
            pose(3)= pose(3)+ dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);              
        end

        % Button pushed function: RotZ1Button
        function RotZ1ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dRotZEdit.Value)*pi/180;
            pose(4)= pose(5)- dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);              
        end

        % Button pushed function: RotZ2Button
        function RotZ2ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dRotZEdit.Value)*pi/180;
            pose(4)= pose(4)+ dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);              
        end

        % Button pushed function: RotY1Button
        function RotY1ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dRotYEdit.Value)*pi/180;
            pose(5)= pose(5)- dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);              
        end

        % Button pushed function: RotY2Button
        function RotY2ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dRotYEdit.Value)*pi/180;
            pose(5)= pose(5)+ dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);              
        end

        % Button pushed function: RotX1Button
        function RotX1ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dRotXEdit.Value)*pi/180;
            pose(6)= pose(6)- dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);              
        end

        % Button pushed function: RotX2Button
        function RotX2ButtonPushed(app, event)
            pose=  app.LeerPose();
            dpose= str2double(app.dRotXEdit.Value)*pi/180;
            pose(6)= pose(6)+ dpose;
            app.EscribirPose(pose);  
            app.rob.MoveJ(pose, app.hwobj);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);              
        end

        % Button pushed function: ImportarButton
        function ImportarButtonPushed(app, event)
            % Importa robot de workspace
            app.rob= evalin('base', app.robEdit.Value);
            q= app.rob.Joint;
            app.EscribirJointEdit(q);
            app.EscribirJointSlider(q);
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose;
            app.EscribirPose(pose);
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            % Exporta robot de workspace
            assignin('base', app.robEdit.Value, app.rob);
        end

        % Callback function
        function HwobjEditValueChanged(app, event)
           app.hwobj= evalin('base', app.HwobjEdit.Value);


        end

        % Callback function
        function HtoolEditValueChanged(app, event)
            disp('Htool')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TeacherABBirb120Label = uilabel(app.UIFigure);
            app.TeacherABBirb120Label.Text = 'Teacher ABB irb120';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-165 165];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q1Slider.Tag = 'theta1';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-110 110];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-110 70];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-160 160];
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-120 120];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.Limits = [-400 400];
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q1Edit = uieditfield(app.UIFigure, 'text');
            app.q1Edit.ValueChangedFcn = createCallbackFcn(app, @q1EditValueChanged, true);
            app.q1Edit.Value = 'q1';
            app.q2Edit = uieditfield(app.UIFigure, 'text');
            app.q2Edit.ValueChangedFcn = createCallbackFcn(app, @q2EditValueChanged, true);
            app.q2Edit.Value = 'q2';
            app.q3Edit = uieditfield(app.UIFigure, 'text');
            app.q3Edit.ValueChangedFcn = createCallbackFcn(app, @q3EditValueChanged, true);
            app.q3Edit.Value = 'q3';
            app.q4Edit = uieditfield(app.UIFigure, 'text');
            app.q4Edit.ValueChangedFcn = createCallbackFcn(app, @q4EditValueChanged, true);
            app.q4Edit.Value = 'q4';
            app.q5Edit = uieditfield(app.UIFigure, 'text');
            app.q5Edit.ValueChangedFcn = createCallbackFcn(app, @q5EditValueChanged, true);
            app.q5Edit.Value = 'q5';
            app.q6Edit = uieditfield(app.UIFigure, 'text');
            app.q6Edit.ValueChangedFcn = createCallbackFcn(app, @q6EditValueChanged, true);
            app.q6Edit.Value = 'q5';
            app.PosXEdit = uieditfield(app.UIFigure, 'text');
            app.PosXEdit.ValueChangedFcn = createCallbackFcn(app, @PosXEditValueChanged, true);
            app.PosXEdit.Value = 'PosX';
            app.PosZEdit = uieditfield(app.UIFigure, 'text');
            app.PosZEdit.ValueChangedFcn = createCallbackFcn(app, @PosZEditValueChanged, true);
            app.PosZEdit.Value = 'PosZ';
            app.RotZEdit = uieditfield(app.UIFigure, 'text');
            app.RotZEdit.ValueChangedFcn = createCallbackFcn(app, @RotZEditValueChanged, true);
            app.RotZEdit.Value = 'RotZ';
            app.RotYEdit = uieditfield(app.UIFigure, 'text');
            app.RotYEdit.ValueChangedFcn = createCallbackFcn(app, @RotYEditValueChanged, true);
            app.RotYEdit.Value = 'RotY';
            app.RotXEdit = uieditfield(app.UIFigure, 'text');
            app.RotXEdit.ValueChangedFcn = createCallbackFcn(app, @RotXEditValueChanged, true);
            app.RotXEdit.Value = 'RotX';
            app.PosYEdit = uieditfield(app.UIFigure, 'text');
            app.PosYEdit.ValueChangedFcn = createCallbackFcn(app, @PosYEditValueChanged, true);
            app.PosYEdit.Value = 'PosY';
            app.PosX2Button = uibutton(app.UIFigure, 'push');
            app.PosX2Button.ButtonPushedFcn = createCallbackFcn(app, @PosX2ButtonPushed, true);
            app.PosX2Button.Text = '>';
            app.PosX1Button = uibutton(app.UIFigure, 'push');
            app.PosX1Button.ButtonPushedFcn = createCallbackFcn(app, @PosX1ButtonPushed, true);
            app.PosX1Button.Text = '<';
            app.dPosXEdit = uieditfield(app.UIFigure, 'text');
            app.dPosXEdit.Value = '10';
            app.PosY2Button = uibutton(app.UIFigure, 'push');
            app.PosY2Button.ButtonPushedFcn = createCallbackFcn(app, @PosY2ButtonPushed, true);
            app.PosY2Button.Text = '>';
            app.PosY1Button = uibutton(app.UIFigure, 'push');
            app.PosY1Button.ButtonPushedFcn = createCallbackFcn(app, @PosY1ButtonPushed, true);
            app.PosY1Button.Text = '<';
            app.dPosYEdit = uieditfield(app.UIFigure, 'text');
            app.dPosYEdit.Value = '10';
            app.PosZ2Button = uibutton(app.UIFigure, 'push');
            app.PosZ2Button.ButtonPushedFcn = createCallbackFcn(app, @PosZ2ButtonPushed, true);
            app.PosZ2Button.Text = '>';
            app.PosZ1Button = uibutton(app.UIFigure, 'push');
            app.PosZ1Button.ButtonPushedFcn = createCallbackFcn(app, @PosZ1ButtonPushed, true);
            app.PosZ1Button.Text = '<';
            app.dPosZEdit = uieditfield(app.UIFigure, 'text');
            app.dPosZEdit.Value = '10';
            app.RotZ2Button = uibutton(app.UIFigure, 'push');
            app.RotZ2Button.ButtonPushedFcn = createCallbackFcn(app, @RotZ2ButtonPushed, true);
            app.RotZ2Button.Text = '>';
            app.RotZ1Button = uibutton(app.UIFigure, 'push');
            app.RotZ1Button.ButtonPushedFcn = createCallbackFcn(app, @RotZ1ButtonPushed, true);
            app.RotZ1Button.Text = '<';
            app.dRotZEdit = uieditfield(app.UIFigure, 'text');
            app.dRotZEdit.Value = '10';
            app.RotY2Button = uibutton(app.UIFigure, 'push');
            app.RotY2Button.ButtonPushedFcn = createCallbackFcn(app, @RotY2ButtonPushed, true);
            app.RotY2Button.Text = '>';
            app.RotY1Button = uibutton(app.UIFigure, 'push');
            app.RotY1Button.ButtonPushedFcn = createCallbackFcn(app, @RotY1ButtonPushed, true);
            app.RotY1Button.Text = '<';
            app.dRotYEdit = uieditfield(app.UIFigure, 'text');
            app.dRotYEdit.Value = '10';
            app.RotX2Button = uibutton(app.UIFigure, 'push');
            app.RotX2Button.ButtonPushedFcn = createCallbackFcn(app, @RotX2ButtonPushed, true);
            app.RotX2Button.Text = '>';
            app.RotX1Button = uibutton(app.UIFigure, 'push');
            app.RotX1Button.ButtonPushedFcn = createCallbackFcn(app, @RotX1ButtonPushed, true);
            app.RotX1Button.Text = '<';
            app.dRotXEdit = uieditfield(app.UIFigure, 'text');
            app.dRotXEdit.Value = '10';
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.q1Label = uilabel(app.UIFigure);
            app.q1Label.Text = 'q1';
            app.q2Label = uilabel(app.UIFigure);
            app.q2Label.Text = 'q2';
            app.q3Label = uilabel(app.UIFigure);
            app.q3Label.Text = 'q3';
            app.q4Label = uilabel(app.UIFigure);
            app.q4Label.Text = 'q4';
            app.q5Label = uilabel(app.UIFigure);
            app.q5Label.Text = 'q5';
            app.q6Label = uilabel(app.UIFigure);
            app.q6Label.Text = 'q6';
            app.PosXLabel = uilabel(app.UIFigure);
            app.PosXLabel.Text = 'PosX';
            app.PosYLabel = uilabel(app.UIFigure);
            app.PosYLabel.Text = 'PosY';
            app.PosZLabel = uilabel(app.UIFigure);
            app.PosZLabel.Text = 'PosZ';
            app.RotZLabel = uilabel(app.UIFigure);
            app.RotZLabel.Text = 'RotZ';
            app.RotYLabel = uilabel(app.UIFigure);
            app.RotYLabel.Text = 'RotY';
            app.RotXLabel = uilabel(app.UIFigure);
            app.RotXLabel.Text = 'RotX';
            app.ImportarButton = uibutton(app.UIFigure, 'push');
            app.ImportarButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPushed, true);
            app.ImportarButton.Text = 'Importar';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            app.robEdit = uieditfield(app.UIFigure, 'text');
            app.robEdit.Value = 'rob';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAbbIrb120(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end