classdef appMover < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LimRotLabel         matlab.ui.control.Label
        LimPosLabel         matlab.ui.control.Label
        limxyzEdit          matlab.ui.control.EditField
        limdegEdit          matlab.ui.control.EditField
        MoveAbsJ            matlab.ui.control.Button
        qEdit               matlab.ui.control.EditField
        EstacionEdit        matlab.ui.control.EditField
        EstacinLabel        matlab.ui.control.Label
        Introd              matlab.ui.control.Button
        ExportarPunto       matlab.ui.control.Button
        ImportarPunto       matlab.ui.control.Button
        puntoEdit           matlab.ui.control.EditField
        PoseLabel           matlab.ui.control.Label
        HowbjEdit           matlab.ui.control.EditField
        WobjToolLabel       matlab.ui.control.Label
        TcpPiezaEdit        matlab.ui.control.EditField
        CinPiezaLabel       matlab.ui.control.Label
        RxLabel             matlab.ui.control.Label
        RyLabel             matlab.ui.control.Label
        RzLabel             matlab.ui.control.Label
        ZLabel              matlab.ui.control.Label
        YLabel              matlab.ui.control.Label
        XLabel              matlab.ui.control.Label
        MoverTCPPiezaLabel  matlab.ui.control.Label
        RxEdit              matlab.ui.control.EditField
        RyEdit              matlab.ui.control.EditField
        RzEdit              matlab.ui.control.EditField
        zEdit               matlab.ui.control.EditField
        yEdit               matlab.ui.control.EditField
        xEdit               matlab.ui.control.EditField
        RxSlider            matlab.ui.control.Slider
        RySlider            matlab.ui.control.Slider
        RzSlider            matlab.ui.control.Slider
        zSlider             matlab.ui.control.Slider
        ySlider             matlab.ui.control.Slider
        xSlider             matlab.ui.control.Slider
    end

    
    properties (Access = private)
        elemento % Objeto Kin/Pieza
        hwobj
        punto % Punto donde está el TCP/Pieza
        estacion
        mRad
        mmDeg
      %  aux
    end
    
    methods (Access = private)
        

        function Init(app)
            app.elemento= [];
            app.hwobj= eye(4);
            app.mmDeg= @(x) [x(1:3)*1e3, x(4:6)*180/pi];
            app.mRad= @(x) [x(1:3)*1e-3, x(4:6)*pi/180];
            app.punto= zeros(1,6);
            app.estacion= '';
            app.qEdit.Value= '';
         %   app.aux= Hmat;
         %   app.aux.Rad;

            app.EstacionEdit.Value= '';
            app.TcpPiezaEdit.Value= '';
            app.HowbjEdit.Value= '';
            app.xEdit.Value='';
            app.yEdit.Value='';
            app.zEdit.Value='';
            app.RzEdit.Value='';
            app.RyEdit.Value='';
            app.RxEdit.Value='';
            limxyz= str2double(app.limxyzEdit.Value);
            limdeg= str2double(app.limdegEdit.Value);
            app.xSlider.Limits = [-limxyz, limxyz];
            app.ySlider.Limits = [-limxyz, limxyz];
            app.zSlider.Limits = [-limxyz, limxyz];
            app.RzSlider.Limits = [-limdeg, limdeg];
            app.RySlider.Limits = [-limdeg, limdeg];
            app.RxSlider.Limits = [-limdeg, limdeg];           
        end

        function EscribirPunto(app)
            app.xEdit.Value= sprintf('%.0f',app.punto(1));
            app.yEdit.Value= sprintf('%.0f',app.punto(2));
            app.zEdit.Value= sprintf('%0.f',app.punto(3));
            app.RzEdit.Value= sprintf('%0.f',app.punto(4));
            app.RyEdit.Value= sprintf('%0.f',app.punto(5));
            app.RxEdit.Value= sprintf('%0.f',app.punto(6));
        end

        function EscribirJoint(app)
           if isa(app.elemento,'Cin')
              joint= app.elemento.Joint()*180/pi;
              cad= '';
              cad(1)= '[';
              for i= 1:length(joint)
                 cad= [cad, sprintf(' %.0f', joint(i)),' ,'];
              end
              cad(end)= ']';
              app.qEdit.Value= cad;
           end
        end

        function HwobjChange(app)
            hold= app.hwobj;
            if isempty(app.HowbjEdit.Value)
                app.hwobj= eye(4);
            elseif strcmp(app.HowbjEdit.Value, 'Tool')
                %app.hwobj= app.aux.tRzyx(app.mRad(app.punto));
                app.hwobj= Prod(app.mRad(app.punto));
            else % Referencia a una pieza
               % try app.hwobj= evalin('base', app.HowbjEdit.Value);
                try Pieza= evalin('base', app.HowbjEdit.Value);
                catch
                   app.HowbjEdit.Value= 'Error';
                   return
                end
                % Se puede introducir una variable Pieza o H 
                if isa(Pieza,'Pieza')
                   app.hwobj= Pieza.Hbase;
                elseif isa(Pieza,'double')
                   app.hwobj= Pieza;
                else
                   app.HowbjEdit.Value= 'Error';
                   return
                end
            end
            %app.aux.H(app.hwobj\hold*app.aux.tRzyx(app.mRad(app.punto)));
            %app.punto= app.mmDeg(app.aux.tRzyx);
            [~, pose]= Prod(app.hwobj\hold, app.mRad(app.punto));   
            app.punto= app.mmDeg(pose);
        end
        
        function MoverJoint(app)
            if isa(app.elemento,'Cin')

               try eval(['joint=', app.qEdit.Value, ';']);
               catch
                   app.qEdit.Value= 'Error';
               end
               
               app.elemento.MoveAbsJ(joint*pi/180);
               app.punto= app.mmDeg(app.elemento.Pose);
               app.HowbjEdit.Value= '';
               app.hwobj= eye(4);
               app.EscribirPunto;
            else
               app.TcpPiezaEdit.Value= 'Error';
            end
        end


        function Mover(app)
            if isa(app.elemento,'Cin')
                app.elemento.MoveL(app.mRad(app.punto),app.hwobj);
            elseif isa(app.elemento,'Pieza')
                if isempty(app.estacion)
                   app.EstacionEdit.Value= 'Error';
                else
                   app.elemento.Base(app.mRad(app.punto), app.hwobj);
                   assignin('base', app.TcpPiezaEdit.Value, app.elemento); 
                   sim(app.estacion, 0);
                end 
            else
                app.TcpPiezaEdit.Value= 'Error';
            end
            if strcmp(app.HowbjEdit.Value,'Tool')
               app.HowbjEdit.Value= '';
               app.HwobjChange;
               app.HowbjEdit.Value= 'Tool';
               app.HwobjChange;
            end
            app.EscribirPunto;
            app.EscribirJoint;
        end

        function IntrPunto(app)
            app.punto(1)= str2double(app.xEdit.Value);
            app.punto(2)= str2double(app.yEdit.Value);
            app.punto(3)= str2double(app.zEdit.Value);
            app.punto(4)= str2double(app.RzEdit.Value);
            app.punto(5)= str2double(app.RyEdit.Value);
            app.punto(6)= str2double(app.RxEdit.Value);
            app.Mover;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Init;
        end

        % Value changed function: xSlider
        function xSliderValueChanged(app, event)

            app.punto(1)= app.punto(1)+ app.xSlider.Value;
            app.xEdit.Value= sprintf('%.0f', app.punto(1));
            app.xSlider.Value= 0;
            app.Mover;
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)

            app.punto(2)= app.punto(2)+ app.ySlider.Value;
            app.yEdit.Value= sprintf('%.0f', app.punto(2));
            app.ySlider.Value= 0;
            app.Mover;      
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)

            app.punto(3)= app.punto(3)+ app.zSlider.Value;
            app.zEdit.Value= sprintf('%.0f', app.punto(3));
            app.zSlider.Value= 0;
            app.Mover;            
        end

        % Value changed function: RzSlider
        function RzSliderValueChanged(app, event)

            app.punto(4)= app.punto(4)+ app.RzSlider.Value;
            app.RzEdit.Value= sprintf('%.0f', app.punto(4));
            app.RzSlider.Value= 0;
            app.Mover;
        end

        % Value changed function: RySlider
        function RySliderValueChanged(app, event)

            app.punto(5)= app.punto(5)+ app.RySlider.Value;
            app.RyEdit.Value= sprintf('%.0f', app.punto(5));
            app.RySlider.Value= 0;
            app.Mover;
        end

        % Value changed function: RxSlider
        function RxSliderValueChanged(app, event)

            app.punto(6)= app.punto(6)+ app.RxSlider.Value;
            app.RxEdit.Value= sprintf('%.0f', app.punto(6));
            app.RxSlider.Value= 0;
            app.Mover;    
        end

        % Value changed function: xEdit
        function xEditValueChanged(app, event)
            %value = app.xEdit.Value;
            app.IntrPunto;
        end

        % Value changed function: yEdit
        function yEditValueChanged(app, event)
            %value = app.yEdit.Value;
            app.IntrPunto;
        end

        % Value changed function: zEdit
        function zEditValueChanged(app, event)
            %value = app.zEdit.Value;
            app.IntrPunto;
        end

        % Value changed function: RzEdit
        function RzEditValueChanged(app, event)
            %value = app.RzEdit.Value;
            app.IntrPunto;
        end

        % Value changed function: RyEdit
        function RyEditValueChanged(app, event)
            %value = app.RyEdit.Value;
            app.IntrPunto;
        end

        % Value changed function: RxEdit
        function RxEditValueChanged(app, event)
            %value = app.RxEdit.Value;
            app.IntrPunto;
        end

        % Value changed function: TcpPiezaEdit
        function TcpPiezaEditValueChanged(app, event)
           % value = app.TcpPiezaEdit.Value;
           if isempty(app.TcpPiezaEdit.Value)
               app.Init;
               app.TcpPiezaEdit.Value= 'Error';
           else
               try app.elemento= evalin('base', app.TcpPiezaEdit.Value);
               catch
                  app.TcpPiezaEdit.Value= 'Error';
               end
               if isa(app.elemento,'Cin')
                  %app.aux.H(app.hwobj\app.aux.tRzyx(app.elemento.Pose));
                  %app.punto= app.mmDeg(app.aux.tRzyx);
                   [~, pose]= Prod(inv(app.hwobj), app.elemento.Pose);
                   app.punto= app.mmDeg(pose);
               elseif isa(app.elemento,'Pieza')
                  %app.aux.H(app.hwobj\app.aux.tRzyx(app.elemento.base));
                  %app.punto= app.mmDeg(app.aux.tRzyx);
                  [~, pose]= Prod(inv(app.hwobj), app.elemento.base);
                  app.punto= app.mmDeg(pose);
               else
                  app.TcpPiezaEdit.Value= 'Error';
               end
               app.EscribirPunto;
               app.EscribirJoint;
           end
        end

        % Value changed function: HowbjEdit
        function HowbjEditValueChanged(app, event)

            app.HwobjChange();
            app.EscribirPunto;
            app.EscribirJoint;
        end

        % Button pushed function: Introd
        function IntrodButtonPushed(app, event)
            app.IntrPunto;
        end

        % Value changed function: EstacionEdit
        function EstacionEditValueChanged(app, event)

            try app.estacion= evalin('base', app.EstacionEdit.Value);
            catch
               app.EstacionEdit.Value= 'Error';
            end
                            
           % app.estacion= app.EstacionEdit.Value;
        end

        % Button pushed function: MoveAbsJ
        function MoveAbsJButtonPushed(app, event)
           app.MoverJoint;
        end

        % Value changed function: limdegEdit
        function limdegEditValueChanged(app, event)
            limdeg= str2double(app.limdegEdit.Value);
            app.RzSlider.Limits = [-limdeg, limdeg];
            app.RySlider.Limits = [-limdeg, limdeg];
            app.RxSlider.Limits = [-limdeg, limdeg];
    
        end

        % Value changed function: limxyzEdit
        function limxyzEditValueChanged(app, event)
            limxyz= str2double(app.limxyzEdit.Value);
            app.xSlider.Limits = [-limxyz, limxyz];
            app.ySlider.Limits = [-limxyz, limxyz];
            app.zSlider.Limits = [-limxyz, limxyz];            
        end

        % Value changed function: qEdit
        function qEditValueChanged(app, event)
            app.MoverJoint;
        end

        % Button pushed function: ExportarPunto
        function ExportarPuntoButtonPushed(app, event)
           assignin('base', app.puntoEdit.Value, app.punto);
        end

        % Button pushed function: ImportarPunto
        function ImportarPuntoButtonPushed(app, event)
            try app.punto= evalin('base', app.puntoEdit.Value);
            catch
               app.puntoEdit.Value= 'Error';
            end
            
            app.Mover; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.Limits = [-100 100];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanged, true);
            app.xSlider.Tag = 'theta1';
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.Limits = [-100 100];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.Limits = [-100 100];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.RzSlider = uislider(app.UIFigure);
            app.RzSlider.Limits = [-30 30];
            app.RzSlider.ValueChangedFcn = createCallbackFcn(app, @RzSliderValueChanged, true);
            app.RySlider = uislider(app.UIFigure);
            app.RySlider.Limits = [-30 30];
            app.RySlider.ValueChangedFcn = createCallbackFcn(app, @RySliderValueChanged, true);
            app.RxSlider = uislider(app.UIFigure);
            app.RxSlider.Limits = [-30 30];
            app.RxSlider.ValueChangedFcn = createCallbackFcn(app, @RxSliderValueChanged, true);
            app.xEdit = uieditfield(app.UIFigure, 'text');
            app.xEdit.ValueChangedFcn = createCallbackFcn(app, @xEditValueChanged, true);
            app.xEdit.Value = 'x';
            app.yEdit = uieditfield(app.UIFigure, 'text');
            app.yEdit.ValueChangedFcn = createCallbackFcn(app, @yEditValueChanged, true);
            app.yEdit.Value = 'y';
            app.zEdit = uieditfield(app.UIFigure, 'text');
            app.zEdit.ValueChangedFcn = createCallbackFcn(app, @zEditValueChanged, true);
            app.zEdit.Value = 'z';
            app.RzEdit = uieditfield(app.UIFigure, 'text');
            app.RzEdit.ValueChangedFcn = createCallbackFcn(app, @RzEditValueChanged, true);
            app.RzEdit.Value = 'Rz';
            app.RyEdit = uieditfield(app.UIFigure, 'text');
            app.RyEdit.ValueChangedFcn = createCallbackFcn(app, @RyEditValueChanged, true);
            app.RyEdit.Value = 'Ry';
            app.RxEdit = uieditfield(app.UIFigure, 'text');
            app.RxEdit.ValueChangedFcn = createCallbackFcn(app, @RxEditValueChanged, true);
            app.RxEdit.Value = 'Rx';
            app.MoverTCPPiezaLabel = uilabel(app.UIFigure);
            app.MoverTCPPiezaLabel.Text = 'Mover TCP/Pieza';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.RzLabel = uilabel(app.UIFigure);
            app.RzLabel.Text = 'Rz';
            app.RyLabel = uilabel(app.UIFigure);
            app.RyLabel.Text = 'Ry';
            app.RxLabel = uilabel(app.UIFigure);
            app.RxLabel.Text = 'Rx';
            app.CinPiezaLabel = uilabel(app.UIFigure);
            app.CinPiezaLabel.Text = 'Cin/Pieza';
            app.TcpPiezaEdit = uieditfield(app.UIFigure, 'text');
            app.TcpPiezaEdit.ValueChangedFcn = createCallbackFcn(app, @TcpPiezaEditValueChanged, true);
            app.TcpPiezaEdit.Value = 'Cin/Pieza';
            app.WobjToolLabel = uilabel(app.UIFigure);
            app.WobjToolLabel.Text = 'Wobj(Tool)';
            app.HowbjEdit = uieditfield(app.UIFigure, 'text');
            app.HowbjEdit.ValueChangedFcn = createCallbackFcn(app, @HowbjEditValueChanged, true);
            app.HowbjEdit.Value = 'Howbj';
            app.PoseLabel = uilabel(app.UIFigure);
            app.PoseLabel.Text = 'Pose ';
            app.puntoEdit = uieditfield(app.UIFigure, 'text');
            app.puntoEdit.Value = 'punto';
            app.ImportarPunto = uibutton(app.UIFigure, 'push');
            app.ImportarPunto.ButtonPushedFcn = createCallbackFcn(app, @ImportarPuntoButtonPushed, true);
            app.ImportarPunto.Text = 'Importar';
            app.ExportarPunto = uibutton(app.UIFigure, 'push');
            app.ExportarPunto.ButtonPushedFcn = createCallbackFcn(app, @ExportarPuntoButtonPushed, true);
            app.ExportarPunto.Text = 'Exportar';
            app.Introd = uibutton(app.UIFigure, 'push');
            app.Introd.ButtonPushedFcn = createCallbackFcn(app, @IntrodButtonPushed, true);
            app.Introd.Text = 'Introd';
            app.EstacinLabel = uilabel(app.UIFigure);
            app.EstacinLabel.Text = 'Estación';
            app.EstacionEdit = uieditfield(app.UIFigure, 'text');
            app.EstacionEdit.ValueChangedFcn = createCallbackFcn(app, @EstacionEditValueChanged, true);
            app.EstacionEdit.Value = 'Estacion';
            app.qEdit = uieditfield(app.UIFigure, 'text');
            app.qEdit.ValueChangedFcn = createCallbackFcn(app, @qEditValueChanged, true);
            app.qEdit.Value = '[0, 0, 0, 0, 0, 0]';
            app.MoveAbsJ = uibutton(app.UIFigure, 'push');
            app.MoveAbsJ.ButtonPushedFcn = createCallbackFcn(app, @MoveAbsJButtonPushed, true);
            app.MoveAbsJ.Text = 'MoveAbsJ';
            app.limdegEdit = uieditfield(app.UIFigure, 'text');
            app.limdegEdit.ValueChangedFcn = createCallbackFcn(app, @limdegEditValueChanged, true);
            app.limdegEdit.Value = '30';
            app.limxyzEdit = uieditfield(app.UIFigure, 'text');
            app.limxyzEdit.ValueChangedFcn = createCallbackFcn(app, @limxyzEditValueChanged, true);
            app.limxyzEdit.Value = '100';
            app.LimPosLabel = uilabel(app.UIFigure);
            app.LimPosLabel.Text = 'LimPos';
            app.LimRotLabel = uilabel(app.UIFigure);
            app.LimRotLabel.Text = 'LimRot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMover

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end