classdef appNiryo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        IPEditField            matlab.ui.control.EditField
        IPEditFieldLabel       matlab.ui.control.Label
        ZLabel                 matlab.ui.control.Label
        YLabel                 matlab.ui.control.Label
        XLabel                 matlab.ui.control.Label
        PosZ                   matlab.ui.control.EditField
        PosY                   matlab.ui.control.EditField
        PosX                   matlab.ui.control.EditField
        LimZ                   matlab.ui.control.EditField
        LimY                   matlab.ui.control.EditField
        LimX                   matlab.ui.control.EditField
        ZSlider                matlab.ui.control.Slider
        ZSliderLabel           matlab.ui.control.Label
        YSlider                matlab.ui.control.Slider
        YSliderLabel           matlab.ui.control.Label
        XSlider                matlab.ui.control.Slider
        XSliderLabel           matlab.ui.control.Label
        robEdit                matlab.ui.control.EditField
        q6Edit                 matlab.ui.control.EditField
        q5Edit                 matlab.ui.control.EditField
        q4Edit                 matlab.ui.control.EditField
        q3Edit                 matlab.ui.control.EditField
        q2Edit                 matlab.ui.control.EditField
        q1Edit                 matlab.ui.control.EditField
        PosicinactualTCPLabel  matlab.ui.control.Label
        Importar               matlab.ui.control.Button
        NiryoNedLabel          matlab.ui.control.Label
        PinzaLabel             matlab.ui.control.Label
        CerrarPinza            matlab.ui.control.StateButton
        AbrirPinza             matlab.ui.control.StateButton
        Exportar               matlab.ui.control.Button
        MoveJ                  matlab.ui.control.Button
        RobotLista             matlab.ui.container.ButtonGroup
        RealyMatlabButton      matlab.ui.control.ToggleButton
        MatlabButton           matlab.ui.control.ToggleButton
        q6Slider               matlab.ui.control.Slider
        q6SliderLabel          matlab.ui.control.Label
        q5Slider               matlab.ui.control.Slider
        q5SliderLabel          matlab.ui.control.Label
        q4Slider               matlab.ui.control.Slider
        q4SliderLabel          matlab.ui.control.Label
        q3Slider               matlab.ui.control.Slider
        q3SliderLabel          matlab.ui.control.Label
        q2Slider               matlab.ui.control.Slider
        q2SliderLabel          matlab.ui.control.Label
        q1Slider               matlab.ui.control.Slider
        q1SliderLabel          matlab.ui.control.Label
        Inicio                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        rob % Objeto Kin
        robot %real
    end

    
    methods (Access = private)

        function q = LeerJointSlider(app) %Pone en la variable q el valor de la cremallera
               q= zeros(1,6);
               %app.q1Slider.Value;
               q(1)= app.q1Slider.Value;
               q(2)= app.q2Slider.Value;
               q(3)= app.q3Slider.Value;
               q(4)= app.q4Slider.Value;
               q(5)= app.q5Slider.Value;
               q(6)= app.q6Slider.Value;
               q= q*pi/180;         
        end

        function EscribirJointSlider(app, q) %Escribe en la cremallera el valor de q
               q= q*180/pi;
               app.q1Slider.Value= q(1);
               app.q2Slider.Value= q(2);
               app.q3Slider.Value= q(3);
               app.q4Slider.Value= q(4);
               app.q5Slider.Value= q(5);
               app.q6Slider.Value= q(6);
        end

        function q= LeerJointEdit(app) %Pone en la variable q el valor puesto en la casilla qEdit
               q= zeros(1,6);
               q(1)= str2double(app.q1Edit.Value); %Pasa de texto a número
               q(2)= str2double(app.q2Edit.Value);
               q(3)= str2double(app.q3Edit.Value);
               q(4)= str2double(app.q4Edit.Value);
               q(5)= str2double(app.q5Edit.Value);
               q(6)= str2double(app.q6Edit.Value);
               q= q*pi/180;     
        end

        function EscribirJointEdit(app, q) %Escribe en qEdit el valor de q
               q= q*180/pi;
               app.q1Edit.Value= sprintf('%.0f', q(1)); %Pasa de número a texto, con 0 decimales
               app.q2Edit.Value= sprintf('%.0f', q(2));
               app.q3Edit.Value= sprintf('%.0f', q(3));
               app.q4Edit.Value= sprintf('%.0f', q(4));
               app.q5Edit.Value= sprintf('%.0f', q(5));
               app.q6Edit.Value= sprintf('%.0f', q(6));
        end

        function pose= LeerPose(app)  %Lee la posición absoluta de Tcp escrita en Posxyz y la escribe en pose 
                pose= zeros(1,3);
                pose(1)= str2double(app.PosX.Value); %Pasa de texto a número
                pose(2)= str2double(app.PosY.Value);
                pose(3)= str2double(app.PosZ.Value);
                pose(1:3)= pose(1:3)*1e-3;             
        end

        function EscribirPose(app, pose) %Escribe en la casilla Posxyz la posición del tcp (pose)
               pose(1:3)= pose(1:3)*1e3;              
               app.PosX.Value= sprintf('%.0f', pose(1));
               app.PosY.Value= sprintf('%.0f', pose(2));
               app.PosZ.Value= sprintf('%.0f', pose(3));         
        end
        
        function DibujarPunto(app) %Dibuja el punto de la posición del tcp
            pose= LeerPose(app);
            punto= repmat(pose,[3,1])+ randn(3,3)*1e-5;
            app.rob.Show([],punto)
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, rob, robot)
            
            if nargin < 2
                error('appNiryo(rob)');
            end
            app.rob= rob; % robot Matlab
            app.robot = []; %real

            %app.rob= evalin('base', 'rob');
            %app.hwobj= [eye(3),zeros(3,1); zeros(1,3),1]; % objeto trabajo
            q= app.rob.Joint;
            pose= app.rob.Pose;
            app.EscribirJointSlider(q);
            app.EscribirJointEdit(q);
            app.EscribirPose(pose);

%             % Establecer los límites de las cremalleras
%             app.q1Slider.Limits = [-170,170];
%             app.q2Slider.Limits = [-120,35];
%             app.q3Slider.Limits = [-77,90];
%             app.q4Slider.Limits = [-120,120];
%             app.q5Slider.Limits = [-100,55];
%             app.q6Slider.Limits = [-145,145];

            app.MatlabButton.Value = 1; %Siempre se inicia con el boton Matlab pulsado


        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            %value = app.q1Slider.Value;
            q= app.LeerJointSlider(); %Lee el número de la cremallera
            app.EscribirJointEdit(q); %Escribe el número en la casilla
            app.rob.MoveAbsJ(q); %Mueve el robot a la posicion dada
            pose= app.rob.Pose; % Guarda en pos la posición del robot
            app.EscribirPose(pose); % Escribe la posición en casillas XYZ

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q); %Robot Real
               
            end
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            %value = app.q2Slider.Value;
            q= app.LeerJointSlider(); 
            app.EscribirJointEdit(q); 
            app.rob.MoveAbsJ(q); 
            pose= app.rob.Pose; 
            app.EscribirPose(pose); 

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
            
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            %value = app.q3Slider.Value;
            q= app.LeerJointSlider(); 
            app.EscribirJointEdit(q); 
            app.rob.MoveAbsJ(q);
            pose= app.rob.Pose; 
            app.EscribirPose(pose); 

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
            
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            %value = app.q4Slider.Value;
            q= app.LeerJointSlider(); 
            app.EscribirJointEdit(q); 
            app.rob.MoveAbsJ(q); 
            pose= app.rob.Pose; 
            app.EscribirPose(pose); 

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
            
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            %value = app.q5Slider.Value;
            q= app.LeerJointSlider(); 
            app.EscribirJointEdit(q); 
            app.rob.MoveAbsJ(q); 
            pose= app.rob.Pose; 
            app.EscribirPose(pose); 

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
            
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            %value = app.q6Slider.Value;
            q= app.LeerJointSlider(); 
            app.EscribirJointEdit(q); 
            app.rob.MoveAbsJ(q); 
            pose= app.rob.Pose; 
            app.EscribirPose(pose); 

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
            
        end

        % Value changed function: q1Edit
        function q1EditValueChanged(app, event)
            %value = app.q1Edit.Value;
            q= app.LeerJointEdit(); %Lee el número de la casilla
            app.EscribirJointSlider(q); %Mueve la cremallera al número dado
            app.rob.MoveAbsJ(q); %Mueve el robot a la posicion dada
            pose= app.rob.Pose; % Guarda en pos la posición del robot
            app.EscribirPose(pose); % Escribe la posición en casillas XYZ
            
            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
        end

        % Value changed function: q2Edit
        function q2EditValueChanged(app, event)
            %value = app.q2Edit.Value;
            q= app.LeerJointEdit(); 
            app.EscribirJointSlider(q); 
            app.rob.MoveAbsJ(q); 
            pose= app.rob.Pose; 
            app.EscribirPose(pose); 

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
        end

        % Value changed function: q3Edit
        function q3EditValueChanged(app, event)
            %value = app.q3Edit.Value;
            q= app.LeerJointEdit(); 
            app.EscribirJointSlider(q); 
            app.rob.MoveAbsJ(q); 
            pose= app.rob.Pose; 
            app.EscribirPose(pose); 

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
        end

        % Value changed function: q4Edit
        function q4EditValueChanged(app, event)
            %value = app.q4Edit.Value;
            q= app.LeerJointEdit(); 
            app.EscribirJointSlider(q); 
            app.rob.MoveAbsJ(q); 
            pose= app.rob.Pose; 
            app.EscribirPose(pose); 

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
        end

        % Value changed function: q5Edit
        function q5EditValueChanged(app, event)
            %value = app.q5Edit.Value;
            q= app.LeerJointEdit(); 
            app.EscribirJointSlider(q); 
            app.rob.MoveAbsJ(q); 
            pose= app.rob.Pose; 
            app.EscribirPose(pose); 

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
        end

        % Value changed function: q6Edit
        function q6EditValueChanged(app, event)
            %value = app.q6Edit.Value;
            q= app.LeerJointEdit(); 
            app.EscribirJointSlider(q); 
            app.rob.MoveAbsJ(q); 
            pose= app.rob.Pose; 
            app.EscribirPose(pose); 

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
        end

        % Button pushed function: Inicio
        function InicioButtonPushed(app, event)
            q= [0,0,0,0,0,0]*pi/180; %Posición de inicio
            app.rob.MoveAbsJ(q); %Mueve el robot a la posición inicial
            pose= app.rob.Pose; %Escribe la posición en la variable pose
            app.EscribirJointSlider(q); %Ajusta la cremallera
            app.EscribirJointEdit(q); %Escribe en la casilla lateral de la cremallera
            app.EscribirPose(pose); %Escribe la posición absoluta del tcp en XYZ

            if app.RealyMatlabButton.Value == 1
               app.robot.move_joints(q);
            end
        end

        % Button pushed function: Importar
        function ImportarButtonPushed(app, event)
            app.rob= evalin('base', app.robEdit.Value); %Importa el robot introducido en la casilla rob
            pose= app.rob.Pose;
            app.EscribirPose(pose); %Escribe la posición del roboto importado
            app.DibujarPunto;
        end

        % Button pushed function: Exportar
        function ExportarButtonPushed(app, event)
             assignin('base', app.robEdit.Value, app.rob); %Exporta el robot
        end

        % Value changed function: PosX
        function PosXValueChanged(app, event)
            %value = app.PosX.Value;
            
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            %value = app.XSlider.Value;
            pose=  app.LeerPose(); %Leer la posición actual del tcp
            dpose= (app.XSlider.Value)*1e-3; %Posición relativa escrita en este eje en la cremallera, sirve tanto si es positiva como negativa
            pose(1)= pose(1)+ dpose; %Posición relativa pedida
            app.EscribirPose(pose); %Ecribe la posición en el vector pose 
            app.DibujarPunto; %Dibuja al punto al que va a ir
            app.rob.MoveJ(app.LeerPose); %Se mueve a la posición dada
            app.XSlider.Value = 0; %Vuelve a poner en 0 la cremallera

            %Escribir en las cremalleras q la posición que tiene ahora el
            %robot tras haberlo movido relativamente
            q= app.rob.Joint;
            app.EscribirJointSlider(q);
            app.EscribirJointEdit(q);

            if app.RealyMatlabButton.Value == 1
               q= app.LeerJointSlider(); %Lee la posición del robot de Matlab
               app.robot.move_joints(q); %Mueve al robot real a la posición del de Matlab
            end

        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            %value = app.YSlider.Value;
            pose=  app.LeerPose(); 
            dpose= (app.YSlider.Value)*1e-3; 
            pose(2)= pose(2)+ dpose; 
            app.EscribirPose(pose); 
            app.DibujarPunto; 
            app.rob.MoveJ(app.LeerPose); 
            app.YSlider.Value = 0; 

            q= app.rob.Joint;
            app.EscribirJointSlider(q);
            app.EscribirJointEdit(q);

            if app.RealyMatlabButton.Value == 1
               q= app.LeerJointSlider(); %Lee la posición del robot de Matlab
               app.robot.move_joints(q); %Mueve al robot real a la posición del de Matlab
            end
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            %value = app.ZSlider.Value;
            pose=  app.LeerPose(); 
            dpose= (app.ZSlider.Value)*1e-3; 
            pose(3)= pose(3)+ dpose; 
            app.EscribirPose(pose); 
            app.DibujarPunto; 
            app.rob.MoveJ(app.LeerPose); 
            app.ZSlider.Value = 0; 

            q= app.rob.Joint;
            app.EscribirJointSlider(q);
            app.EscribirJointEdit(q);

            if app.RealyMatlabButton.Value == 1
               q= app.LeerJointSlider(); %Lee la posición del robot de Matlab
               app.robot.move_joints(q); %Mueve al robot real a la posición del de Matlab
            end
        end

        % Value changed function: LimX
        function LimXValueChanged(app, event)
            limX = abs(str2double(app.LimX.Value));
            app.XSlider.Limits = [-limX, limX];
        end

        % Value changed function: LimY
        function LimYValueChanged(app, event)
            limY = abs(str2double(app.LimY.Value));
            app.YSlider.Limits = [-limY, limY];
        end

        % Value changed function: LimZ
        function LimZValueChanged(app, event)
            limZ = abs(str2double(app.LimZ.Value));
            app.ZSlider.Limits = [-limZ, limZ];
            
            
        end

        % Button pushed function: MoveJ
        function MoveJButtonPushed(app, event)
            app.rob.MoveJ(app.LeerPose);
            q= app.rob.Joint;
            app.EscribirJointSlider(q);
            app.EscribirJointEdit(q);

            if app.RealyMatlabButton.Value == 1
               q= app.LeerJointSlider(); %Lee la posición del robot de Matlab
               app.robot.move_joints(q); %Mueve al robot real a la posición del de Matlab
            end
        end

        % Value changed function: PosY
        function PosYValueChanged(app, event)
            %value = app.PosY.Value;
           %ELIMINAR
        end

        % Value changed function: PosZ
        function PosZValueChanged(app, event)
            %value = app.PosZ.Value;
            %ELIMINAR
        end

        % Value changed function: AbrirPinza
        function AbrirPinzaValueChanged(app, event)
            %value = app.AbrirPinza.Value;
            if app.AbrirPinza.Value == 1
               app.CerrarPinza.Value == 0
               app.robot.update_tool(); %Detecta y configura la herramienta
               app.robot.release_with_tool(); %Suelta el objeto
            end
        end

        % Value changed function: CerrarPinza
        function CerrarPinzaValueChanged(app, event)
            %value = app.CerrarPinza.Value;
            if app.CerrarPinza.Value == 1
               app.AbrirPinza.Value == 0
               app.robot.update_tool(); %Detecta y configura la herramienta
               app.robot.grasp_with_tool(); %Agarra el objeto
            end
        end

        % Selection changed function: RobotLista
        function RobotListaSelectionChanged(app, event)
            %selectedButton = app.RobotLista.SelectedObject;
            if  app.RealyMatlabButton.Value == 1    
                pyLines('from pyniryo import *');
                robot_ip_address = app.IPEditField.Value;
                app.robot = NiryoRobot(robot_ip_address);
                app.robot.calibrate_auto();
                app.RealyMatlabButton.FontColor = [0.23,0.56,0.28]; %Indica conexion con el robot real
            end
            if app.MatlabButton.Value == 1 %Si se pulsa el botón de Matlab y además está conectado el robot real
                if app.RealyMatlabButton.FontColor == [0.23,0.56,0.28]
                    app.robot.close_connection(); %desconectar el robot real
                    app.RealyMatlabButton.FontColor = [0.00,0.45,0.74]; %Cambiar al color azul
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Inicio = uibutton(app.UIFigure, 'push');
            app.Inicio.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.Inicio.Text = 'Inicio';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-170 170];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-120 35];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-77 90];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-120 120];
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-100 55];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.Limits = [-145 145];
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.RobotLista = uibuttongroup(app.UIFigure);
            app.RobotLista.SelectionChangedFcn = createCallbackFcn(app, @RobotListaSelectionChanged, true);
            app.RobotLista.Title = 'Robot';
            app.MatlabButton = uitogglebutton(app.RobotLista);
            app.MatlabButton.Text = 'Matlab';
            app.MatlabButton.Value = true;
            app.RealyMatlabButton = uitogglebutton(app.RobotLista);
            app.RealyMatlabButton.Text = 'Real y Matlab';
            app.MoveJ = uibutton(app.UIFigure, 'push');
            app.MoveJ.ButtonPushedFcn = createCallbackFcn(app, @MoveJButtonPushed, true);
            app.MoveJ.Text = 'MoveJ';
            app.Exportar = uibutton(app.UIFigure, 'push');
            app.Exportar.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.Exportar.Text = 'Exportar';
            app.AbrirPinza = uibutton(app.UIFigure, 'state');
            app.AbrirPinza.ValueChangedFcn = createCallbackFcn(app, @AbrirPinzaValueChanged, true);
            app.AbrirPinza.Text = 'Abrir';
            app.CerrarPinza = uibutton(app.UIFigure, 'state');
            app.CerrarPinza.ValueChangedFcn = createCallbackFcn(app, @CerrarPinzaValueChanged, true);
            app.CerrarPinza.Text = {'Cerrar'; ''};
            app.PinzaLabel = uilabel(app.UIFigure);
            app.PinzaLabel.Text = 'Pinza';
            app.NiryoNedLabel = uilabel(app.UIFigure);
            app.NiryoNedLabel.Text = 'Niryo Ned';
            app.Importar = uibutton(app.UIFigure, 'push');
            app.Importar.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPushed, true);
            app.Importar.Text = 'Importar';
            app.PosicinactualTCPLabel = uilabel(app.UIFigure);
            app.PosicinactualTCPLabel.Text = 'Posición actual TCP';
            app.q1Edit = uieditfield(app.UIFigure, 'text');
            app.q1Edit.ValueChangedFcn = createCallbackFcn(app, @q1EditValueChanged, true);
            app.q1Edit.Value = '0';
            app.q2Edit = uieditfield(app.UIFigure, 'text');
            app.q2Edit.ValueChangedFcn = createCallbackFcn(app, @q2EditValueChanged, true);
            app.q2Edit.Value = '0';
            app.q3Edit = uieditfield(app.UIFigure, 'text');
            app.q3Edit.ValueChangedFcn = createCallbackFcn(app, @q3EditValueChanged, true);
            app.q3Edit.Value = '0';
            app.q4Edit = uieditfield(app.UIFigure, 'text');
            app.q4Edit.ValueChangedFcn = createCallbackFcn(app, @q4EditValueChanged, true);
            app.q4Edit.Value = '0';
            app.q5Edit = uieditfield(app.UIFigure, 'text');
            app.q5Edit.ValueChangedFcn = createCallbackFcn(app, @q5EditValueChanged, true);
            app.q5Edit.Value = '0';
            app.q6Edit = uieditfield(app.UIFigure, 'text');
            app.q6Edit.ValueChangedFcn = createCallbackFcn(app, @q6EditValueChanged, true);
            app.q6Edit.Value = '0';
            app.robEdit = uieditfield(app.UIFigure, 'text');
            app.robEdit.Value = 'rob';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-100 100];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-100 100];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-100 100];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.LimX = uieditfield(app.UIFigure, 'text');
            app.LimX.ValueChangedFcn = createCallbackFcn(app, @LimXValueChanged, true);
            app.LimX.Value = '100';
            app.LimY = uieditfield(app.UIFigure, 'text');
            app.LimY.ValueChangedFcn = createCallbackFcn(app, @LimYValueChanged, true);
            app.LimY.Value = '100';
            app.LimZ = uieditfield(app.UIFigure, 'text');
            app.LimZ.ValueChangedFcn = createCallbackFcn(app, @LimZValueChanged, true);
            app.LimZ.Value = '100';
            app.PosX = uieditfield(app.UIFigure, 'text');
            app.PosX.ValueChangedFcn = createCallbackFcn(app, @PosXValueChanged, true);
            app.PosX.Value = 'X';
            app.PosY = uieditfield(app.UIFigure, 'text');
            app.PosY.ValueChangedFcn = createCallbackFcn(app, @PosYValueChanged, true);
            app.PosY.Value = 'Y';
            app.PosZ = uieditfield(app.UIFigure, 'text');
            app.PosZ.ValueChangedFcn = createCallbackFcn(app, @PosZValueChanged, true);
            app.PosZ.Value = 'Z';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.IPEditFieldLabel = uilabel(app.UIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.UIFigure, 'text');
            app.IPEditField.Value = '10.10.10.10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appNiryo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end