classdef appRastreador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        InicioButton         matlab.ui.control.Button
        RastreadorLabel      matlab.ui.control.Label
        MoveLButton          matlab.ui.control.Button
        MoveJButton          matlab.ui.control.Button
        puntoEdit            matlab.ui.control.EditField
        ExportarButtonPunto  matlab.ui.control.Button
        ImportarButtonPunto  matlab.ui.control.Button
        robEdit              matlab.ui.control.EditField
        ExportarButton       matlab.ui.control.Button
        ImportarButton       matlab.ui.control.Button
        PosZLabel            matlab.ui.control.Label
        PosYLabel            matlab.ui.control.Label
        PosXLabel            matlab.ui.control.Label
        dPosZEdit            matlab.ui.control.EditField
        PosZ1Button          matlab.ui.control.Button
        PosZ2Button          matlab.ui.control.Button
        dPosYEdit            matlab.ui.control.EditField
        PosY1Button          matlab.ui.control.Button
        PosY2Button          matlab.ui.control.Button
        dPosXEdit            matlab.ui.control.EditField
        PosX1Button          matlab.ui.control.Button
        PosX2Button          matlab.ui.control.Button
        PosYEdit             matlab.ui.control.EditField
        PosZEdit             matlab.ui.control.EditField
        PosXEdit             matlab.ui.control.EditField
    end

    
    properties (Access = private)
        rob % Objeto RigidBody
    end
    
    methods (Access = private)
                
        function pos= LeerPos(app)
                pos= zeros(1,3);
                pos(1)= str2double(app.PosXEdit.Value);
                pos(2)= str2double(app.PosYEdit.Value);
                pos(3)= str2double(app.PosZEdit.Value);
                pos= pos*1e-3;
        end
        function EscribirPos(app, pos)
               pos(1:3)= pos(1:3)*1e3;
               app.PosXEdit.Value= sprintf('%.0f', pos(1));
               app.PosYEdit.Value= sprintf('%.0f', pos(2));
               app.PosZEdit.Value= sprintf('%.0f', pos(3));
        end

        function DibujarPunto(app)
            pos= LeerPos(app);
            punto= repmat(pos,[3,1])+ randn(3,3)*1e-5;
            app.rob.Show([],punto)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, rob)
            app.rob= rob; % robot
            %app.rob= evalin('base', 'rob');
            pose= app.rob.Pose;
            app.EscribirPos(pose(1:3));
            % Borra memoria para no escribirla
            app.rob.Rec;
            app.rob.Rec(0);
        end

        % Button pushed function: PosX1Button
        function PosX1ButtonPushed(app, event)
            pos=  app.LeerPos();
            dpos= str2double(app.dPosXEdit.Value)*1e-3;
            pos(1)= pos(1)- dpos;
            app.EscribirPos(pos);  
            app.DibujarPunto; 
        end

        % Button pushed function: PosX2Button
        function PosX2ButtonPushed(app, event)
            pos=  app.LeerPos();
            dpos= str2double(app.dPosXEdit.Value)*1e-3;
            pos(1)= pos(1)+ dpos;
            app.EscribirPos(pos);  
            app.DibujarPunto;             
        end

        % Button pushed function: PosY1Button
        function PosY1ButtonPushed(app, event)
            pos=  app.LeerPos();
            dpos= str2double(app.dPosYEdit.Value)*1e-3;
            pos(2)= pos(2)- dpos;
            app.EscribirPos(pos);  
            app.DibujarPunto;             
        end

        % Button pushed function: PosY2Button
        function PosY2ButtonPushed(app, event)
            pos=  app.LeerPos();
            dpos= str2double(app.dPosYEdit.Value)*1e-3;
            pos(2)= pos(2)+ dpos;
            app.EscribirPos(pos);  
            app.DibujarPunto;                         
        end

        % Button pushed function: PosZ1Button
        function PosZ1ButtonPushed(app, event)
            pos=  app.LeerPos();
            dpos= str2double(app.dPosZEdit.Value)*1e-3;
            pos(3)= pos(3)- dpos;
            app.EscribirPos(pos);  
            app.DibujarPunto;                         
        end

        % Button pushed function: PosZ2Button
        function PosZ2ButtonPushed(app, event)
            pos=  app.LeerPos();
            dpos= str2double(app.dPosZEdit.Value)*1e-3;
            pos(3)= pos(3)+ dpos;
            app.EscribirPos(pos);  
            app.DibujarPunto;                                     
        end

        % Value changed function: PosXEdit
        function PosXEditValueChanged(app, event)
           % value = app.PosXEdit.Value;
           app.DibujarPunto; 
        end

        % Value changed function: PosYEdit
        function PosYEditValueChanged(app, event)
            %value = app.PosYEdit.Value;
            app.DibujarPunto;                         
        end

        % Value changed function: PosZEdit
        function PosZEditValueChanged(app, event)
            %value = app.PosZEdit.Value;
            app.DibujarPunto;
        end

        % Button pushed function: ImportarButton
        function ImportarButtonPushed(app, event)
            app.rob= evalin('base', app.robEdit.Value);
            pose= app.rob.Pose;
            app.EscribirPos(pose(1:3));
            app.DibujarPunto;          
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            assignin('base', app.robEdit.Value, app.rob);  
        end

        % Button pushed function: ImportarButtonPunto
        function ImportarButtonPuntoPushed(app, event)

              punto= evalin('base', app.puntoEdit.Value);
              app.EscribirPos(punto);
              app.DibujarPunto;
        end

        % Button pushed function: ExportarButtonPunto
        function ExportarButtonPuntoPushed(app, event)
            punto= app.LeerPos;
            assignin('base', app.puntoEdit.Value, punto);  
        end

        % Button pushed function: MoveJButton
        function MoveJButtonPushed(app, event)
            app.rob.MoveJ(app.LeerPos);
        end

        % Button pushed function: MoveLButton
        function MoveLButtonPushed(app, event)
            app.rob.MoveJ(app.LeerPos);
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            punto= app.rob.Pose;
            app.EscribirPos(punto(1:3));
            app.DibujarPunto;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PosXEdit = uieditfield(app.UIFigure, 'text');
            app.PosXEdit.ValueChangedFcn = createCallbackFcn(app, @PosXEditValueChanged, true);
            app.PosXEdit.Value = 'PosX';
            app.PosZEdit = uieditfield(app.UIFigure, 'text');
            app.PosZEdit.ValueChangedFcn = createCallbackFcn(app, @PosZEditValueChanged, true);
            app.PosZEdit.Value = 'PosZ';
            app.PosYEdit = uieditfield(app.UIFigure, 'text');
            app.PosYEdit.ValueChangedFcn = createCallbackFcn(app, @PosYEditValueChanged, true);
            app.PosYEdit.Value = 'PosY';
            app.PosX2Button = uibutton(app.UIFigure, 'push');
            app.PosX2Button.ButtonPushedFcn = createCallbackFcn(app, @PosX2ButtonPushed, true);
            app.PosX2Button.Text = '>';
            app.PosX1Button = uibutton(app.UIFigure, 'push');
            app.PosX1Button.ButtonPushedFcn = createCallbackFcn(app, @PosX1ButtonPushed, true);
            app.PosX1Button.Text = '<';
            app.dPosXEdit = uieditfield(app.UIFigure, 'text');
            app.dPosXEdit.Value = '50';
            app.PosY2Button = uibutton(app.UIFigure, 'push');
            app.PosY2Button.ButtonPushedFcn = createCallbackFcn(app, @PosY2ButtonPushed, true);
            app.PosY2Button.Text = '>';
            app.PosY1Button = uibutton(app.UIFigure, 'push');
            app.PosY1Button.ButtonPushedFcn = createCallbackFcn(app, @PosY1ButtonPushed, true);
            app.PosY1Button.Text = '<';
            app.dPosYEdit = uieditfield(app.UIFigure, 'text');
            app.dPosYEdit.Value = '50';
            app.PosZ2Button = uibutton(app.UIFigure, 'push');
            app.PosZ2Button.ButtonPushedFcn = createCallbackFcn(app, @PosZ2ButtonPushed, true);
            app.PosZ2Button.Text = '>';
            app.PosZ1Button = uibutton(app.UIFigure, 'push');
            app.PosZ1Button.ButtonPushedFcn = createCallbackFcn(app, @PosZ1ButtonPushed, true);
            app.PosZ1Button.Text = '<';
            app.dPosZEdit = uieditfield(app.UIFigure, 'text');
            app.dPosZEdit.Value = '50';
            app.PosXLabel = uilabel(app.UIFigure);
            app.PosXLabel.Text = 'PosX';
            app.PosYLabel = uilabel(app.UIFigure);
            app.PosYLabel.Text = 'PosY';
            app.PosZLabel = uilabel(app.UIFigure);
            app.PosZLabel.Text = 'PosZ';
            app.ImportarButton = uibutton(app.UIFigure, 'push');
            app.ImportarButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPushed, true);
            app.ImportarButton.Text = 'Importar';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            app.robEdit = uieditfield(app.UIFigure, 'text');
            app.robEdit.Value = 'rob';
            app.ImportarButtonPunto = uibutton(app.UIFigure, 'push');
            app.ImportarButtonPunto.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPuntoPushed, true);
            app.ImportarButtonPunto.Text = 'Importar';
            app.ExportarButtonPunto = uibutton(app.UIFigure, 'push');
            app.ExportarButtonPunto.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPuntoPushed, true);
            app.ExportarButtonPunto.Text = 'Exportar';
            app.puntoEdit = uieditfield(app.UIFigure, 'text');
            app.puntoEdit.Value = 'punto';
            app.MoveJButton = uibutton(app.UIFigure, 'push');
            app.MoveJButton.ButtonPushedFcn = createCallbackFcn(app, @MoveJButtonPushed, true);
            app.MoveJButton.Text = 'MoveJ';
            app.MoveLButton = uibutton(app.UIFigure, 'push');
            app.MoveLButton.ButtonPushedFcn = createCallbackFcn(app, @MoveLButtonPushed, true);
            app.MoveLButton.Text = 'MoveL';
            app.RastreadorLabel = uilabel(app.UIFigure);
            app.RastreadorLabel.Text = 'Rastreador';
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appRastreador(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end